classdef LCOSS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        DataLogPanel                    matlab.ui.container.Panel
        ClearButton                     matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
        CursorCheckBox                  matlab.ui.control.CheckBox
        SetButton_2                     matlab.ui.control.Button
        MHzLabel_2                      matlab.ui.control.Label
        MHzLabel                        matlab.ui.control.Label
        RightCursorEditField            matlab.ui.control.NumericEditField
        RightCursorEditFieldLabel       matlab.ui.control.Label
        LeftCursorEditField             matlab.ui.control.NumericEditField
        LeftCursorEditFieldLabel        matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        LogIntervalEditField            matlab.ui.control.NumericEditField
        LogIntervalEditFieldLabel       matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        DataLogButton                   matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        MeasurementPanel                matlab.ui.container.Panel
        PauseButton                     matlab.ui.control.Button
        SetInitialTrace2Button          matlab.ui.control.Button
        SetInitialTrace1Button          matlab.ui.control.Button
        GetInitialTrace2Button          matlab.ui.control.Button
        GetInitialTrace1Button          matlab.ui.control.Button
        ENASetupPanel                   matlab.ui.container.Panel
        ENAAddressEditFieldLabel        matlab.ui.control.Label
        ENAAddressEditField             matlab.ui.control.EditField
        ConnectButton                   matlab.ui.control.Button
        DisconnectButton                matlab.ui.control.Button
        FindPeaksParameterPanel         matlab.ui.container.Panel
        SetButton                       matlab.ui.control.Button
        MinPeakHeightEditField          matlab.ui.control.NumericEditField
        MinPeakHeightEditFieldLabel     matlab.ui.control.Label
        MinPeakwidthEditField           matlab.ui.control.NumericEditField
        MinPeakwidthEditFieldLabel      matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel         matlab.ui.control.Label
        MinPeakProminanceEditField      matlab.ui.control.NumericEditField
        MinPeakProminanceEditFieldLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        RightPanel                      matlab.ui.container.Panel
        MeasurementsPanel               matlab.ui.container.Panel
        TimeLabel                       matlab.ui.control.Label
        TimeSlider                      matlab.ui.control.Slider
        TimeSliderLabel                 matlab.ui.control.Label
        diffTrace                       matlab.ui.control.UIAxes
        temporalTrace                   matlab.ui.control.UIAxes
        GaussianFitPanel                matlab.ui.container.Panel
        FitParametersTextArea           matlab.ui.control.TextArea
        FitParametersTextAreaLabel      matlab.ui.control.Label
        NumofGaussianPeaksSpinner       matlab.ui.control.Spinner
        NumofGaussianPeaksSpinnerLabel  matlab.ui.control.Label
        GaussianFitButton               matlab.ui.control.Button
        gaussFitTrace                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        ENATest; % visaENA class
        running = false; % system running
        pauseFlag = false; %pause flag
        dataLogFlag = false; %log data flag
        dataLogTimer = timer;   %data log timer
        dataLoadedInterval = 0.1;
        dataLoadedLength = 0;

        leftCursorTemporal;
        rightCursorTemporal;
        leftCursorDiff;
        rightCursorDiff;
    end
    
    methods (Access = private)
        
        function startTest(app)
            app.running = true;
            app.GetInitialTrace1Button.Enable = "on";
            app.GetInitialTrace2Button.Enable = "on";
            app.SetInitialTrace1Button.Enable = "on";
            app.SetInitialTrace2Button.Enable = "on";
            app.DataLogButton.Enable="on";
            app.ResetButton.Enable = "on";
            app.SaveButton.Enable = "on";
            app.PauseButton.Enable = "on";
            app.SetButton.Enable = "on";
            app.SetButton_2.Enable="on";
            app.PauseButton.Text = "Pause";
        end

        function stopTest(app)
            app.running = false;
            app.GetInitialTrace1Button.Enable = "off";
            app.GetInitialTrace2Button.Enable = "off";
            app.SetInitialTrace1Button.Enable = "off";
            app.SetInitialTrace2Button.Enable = "off";
            app.DataLogButton.Enable="off";
            app.ResetButton.Enable = "off";
            app.SaveButton.Enable = "off";
            app.PauseButton.Enable = "off";
            app.SetButton.Enable = "off";
            app.SetButton_2.Enable="off";
            app.PauseButton.Text = "Pause";
        end

        function setupCursor(app)
            app.LeftCursorEditField.Value = app.temporalTrace.XLim(1);
            app.RightCursorEditField.Value = app.temporalTrace.XLim(2);
            app.ENATest.leftCursor = app.LeftCursorEditField.Value;
            app.ENATest.rightCursor = app.RightCursorEditField.Value;

            app.leftCursorTemporal= xline(app.temporalTrace, app.LeftCursorEditField.Value, 'Color', 'blue', 'LineStyle', '--');
            app.rightCursorTemporal= xline(app.temporalTrace, app.RightCursorEditField.Value, 'Color', 'blue', 'LineStyle', '--');
            app.leftCursorDiff= xline(app.diffTrace, app.LeftCursorEditField.Value, 'Color', 'blue', 'LineStyle', '--');
            app.rightCursorDiff= xline(app.diffTrace, app.RightCursorEditField.Value, 'Color', 'blue', 'LineStyle', '--');
            app.leftCursorTemporal.Visible = 'off';
            app.rightCursorTemporal.Visible = 'off';
            app.leftCursorDiff.Visible = 'off';
            app.rightCursorDiff.Visible = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize the application

            app.ENATest = visaENA();
        end

        % Button pushed function: GetInitialTrace1Button
        function GetInitialTrace1ButtonPushed(app, event)
            % get the initialize trace data 1
            
            app.ENATest.getInitialValue1();
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % setup ENA address parameter
            % setup the s11 spectrum visualization

            app.ENATest.initENA(app.ENAAddressEditField.Value);

            app.ENATest.setupVisualization(app.temporalTrace, app.diffTrace);

            app.ENATest.startVisualization();

            app.startTest();

            app.setupCursor();

        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.ENATest.stopVisualization();
            app.ENATest.deleteENA();
            app.stopTest();

        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.ENATest.MinPeakProminance = app.MinPeakProminanceEditField.Value;
            app.ENATest.Threshold = app.ThresholdEditField.Value;
            app.ENATest.MinPeakHeight = app.MinPeakHeightEditField.Value;
            app.ENATest.MinPeakWidth = app.MinPeakwidthEditField.Value;
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.pauseFlag = ~app.pauseFlag;
            switch app.PauseButton.Text
                case 'Resume'
                    app.PauseButton.Text = 'Pause';
                    app.ENATest.startVisualization();
                case 'Pause'
                    app.PauseButton.Text = 'Resume';
                    app.ENATest.stopVisualization();
            end
        end

        % Button pushed function: SetInitialTrace2Button
        function SetInitialTrace2ButtonPushed(app, event)
            app.ENATest.setInitialValue2();
        end

        % Button pushed function: SetInitialTrace1Button
        function SetInitialTrace1ButtonPushed(app, event)
            app.ENATest.setInitialValue1();
        end

        % Button pushed function: GetInitialTrace2Button
        function GetInitialTrace2ButtonPushed(app, event)
            app.ENATest.getInitialValue2();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [filename, pathname] = uiputfile('*.mat', 'Save Data As', 'data.mat');
            if isequal(filename,0) || isequal(pathname,0)
                disp('User cancelled operation');
            else
                % 用户选择了文件名和路径，可以进行存储操作
                fullFilePath = fullfile(pathname, filename);
            end
            app.ENATest.savePath=fullFilePath;
            app.ENATest.SaveData();
        end

        % Button pushed function: DataLogButton
        function DataLogButtonPushed(app, event)
            app.dataLogFlag = ~app.dataLogFlag;
            switch app.dataLogFlag
                case false
                    app.DataLogButton.Text = 'Start Data Log';
                    app.ENATest.dataLogStop();
                case true
                    app.DataLogButton.Text = 'Pause Data Log';
                    app.ENATest.dataLogStart();
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ENATest.initDataToSave();
            app.dataLogFlag=false;
            app.DataLogButton.Text='Start Data Log';
        end

        % Button pushed function: SetButton_2
        function SetButton_2Pushed(app, event)
            app.ENATest.dataLogInterval = app.LogIntervalEditField.Value;
            app.ENATest.dataLogSet();
            app.ENATest.connectionTimerSet();
            app.ENATest.useCursor = app.CursorCheckBox.Value;
            if app.CursorCheckBox.Value
                app.ENATest.leftCursor = app.LeftCursorEditField.Value;
                app.ENATest.rightCursor = app.RightCursorEditField.Value;

                app.leftCursorTemporal.Value=app.LeftCursorEditField.Value;
                app.rightCursorTemporal.Value=app.RightCursorEditField.Value;
                app.leftCursorDiff.Value=app.LeftCursorEditField.Value;
                app.rightCursorDiff.Value=app.RightCursorEditField.Value;
            else

            end
            
        end

        % Button pushed function: GaussianFitButton
        function GaussianFitButtonPushed(app, event)
            app.ENATest.fitGaussNum = app.NumofGaussianPeaksSpinner.Value;
            app.ENATest.fitGaussFun();
            app.ENATest.plotFitGauss(app.gaussFitTrace);
            
            gaussianOutputs = "";
            for i = 1:app.NumofGaussianPeaksSpinner.Value
                tmp = sprintf('Gaussian Peak %d: Center %.2f \t Width %.2f\n', i, app.ENATest.fitGaussParameter(i*2-1), app.ENATest.fitGaussParameter(i*2));
                gaussianOutputs = append(gaussianOutputs, tmp);
            end
            app.FitParametersTextArea.Value = gaussianOutputs;
        end

        % Value changed function: CursorCheckBox
        function CursorCheckBoxValueChanged(app, event)
            value = app.CursorCheckBox.Value;
            switch value
                case true
                    app.LeftCursorEditField.Enable = "on";
                    app.RightCursorEditField.Enable = "on";
                    app.leftCursorTemporal.Visible = 'on';
                    app.leftCursorDiff.Visible = 'on';
                    app.rightCursorTemporal.Visible = 'on';
                    app.rightCursorDiff.Visible = 'on';

                case false
                    app.LeftCursorEditField.Enable = "off";
                    app.RightCursorEditField.Enable = "off";
                    app.leftCursorTemporal.Visible = 'off';
                    app.leftCursorDiff.Visible = 'off';
                    app.rightCursorTemporal.Visible = 'off';
                    app.rightCursorDiff.Visible = 'off';
            end

        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            %open the data path
            [filename, pathname] = uigetfile('*.*', 'Select a logged data');
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User cancelled file selection');
            else
                filepath = fullfile(pathname, filename);
                app.ENATest.dataLoaded = load(filepath);
                validation = app.ENATest.validateDataLoaded();
                if validation
                    %show on the axes
                    app.stopTest();
                    app.ENATest.stopVisualization();
                    app.ENATest.setupVisualization(app.temporalTrace, app.diffTrace);
                    app.ENATest.slideChange(0);
                    app.setupCursor();
                    app.TimeSlider.Visible = "on";
                    app.TimeSlider.Enable = "on";
                    app.ClearButton.Enable = "on";
                    app.TimeLabel.Visible = "on";
                    app.TimeLabel.Text = '0.0 s';
                    app.dataLoadedInterval = app.ENATest.dataLoaded.timeInterval;
                    app.dataLoadedLength = length(app.ENATest.dataLoaded.Frequency);
                else
                    disp("data loadded error");
                end
                
            end
            
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ENATest.slideChange(changingValue);
            frameIdx = ceil(app.dataLoadedLength*changingValue/100);
            timeLabelText = sprintf('%.2f s', frameIdx*app.dataLoadedInterval);
            app.TimeLabel.Text = timeLabelText;

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.temporalTrace);
            cla(app.diffTrace);

            app.TimeSlider.Visible = "off";
            app.TimeSlider.Enable = "off";
            app.TimeLabel.Visible = 'off';
            
            app.ENATest.setupVisualization(app.temporalTrace, app.diffTrace);
            app.ENATest.startVisualization();
            app.startTest();
            app.setupCursor();

            app.ClearButton.Enable = "off";
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.ENATest.delete();
            delete(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {932, 932};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {324, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'resources', 'images', 'LOGO.png');
            app.FindPeaksParameterPanel = uipanel(app.LeftPanel);
            app.FindPeaksParameterPanel.Title = 'Find Peaks Parameter';
            app.MinPeakProminanceEditFieldLabel = uilabel(app.FindPeaksParameterPanel);
            app.MinPeakProminanceEditFieldLabel.Text = 'Min Peak Prominance';
            app.MinPeakProminanceEditField = uieditfield(app.FindPeaksParameterPanel, 'numeric');
            app.MinPeakProminanceEditField.Value = 0.2;
            app.ThresholdEditFieldLabel = uilabel(app.FindPeaksParameterPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.FindPeaksParameterPanel, 'numeric');
            app.MinPeakwidthEditFieldLabel = uilabel(app.FindPeaksParameterPanel);
            app.MinPeakwidthEditFieldLabel.Text = 'Min Peak width';
            app.MinPeakwidthEditField = uieditfield(app.FindPeaksParameterPanel, 'numeric');
            app.MinPeakHeightEditFieldLabel = uilabel(app.FindPeaksParameterPanel);
            app.MinPeakHeightEditFieldLabel.Text = 'Min Peak Height';
            app.MinPeakHeightEditField = uieditfield(app.FindPeaksParameterPanel, 'numeric');
            app.SetButton = uibutton(app.FindPeaksParameterPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Enable = 'off';
            app.SetButton.Text = 'Set';
            app.ENASetupPanel = uipanel(app.LeftPanel);
            app.ENASetupPanel.Title = 'ENA Setup';
            app.DisconnectButton = uibutton(app.ENASetupPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ConnectButton = uibutton(app.ENASetupPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ENAAddressEditField = uieditfield(app.ENASetupPanel, 'text');
            app.ENAAddressEditField.Value = 'USB0::0x2A8D::0x5A01::MY46733702::0::INSTR';
            app.ENAAddressEditFieldLabel = uilabel(app.ENASetupPanel);
            app.ENAAddressEditFieldLabel.Text = 'ENA Address';
            app.MeasurementPanel = uipanel(app.LeftPanel);
            app.MeasurementPanel.Title = 'Measurement';
            app.GetInitialTrace1Button = uibutton(app.MeasurementPanel, 'push');
            app.GetInitialTrace1Button.ButtonPushedFcn = createCallbackFcn(app, @GetInitialTrace1ButtonPushed, true);
            app.GetInitialTrace1Button.Enable = 'off';
            app.GetInitialTrace1Button.Text = 'Get Initial Trace1';
            app.GetInitialTrace2Button = uibutton(app.MeasurementPanel, 'push');
            app.GetInitialTrace2Button.ButtonPushedFcn = createCallbackFcn(app, @GetInitialTrace2ButtonPushed, true);
            app.GetInitialTrace2Button.Enable = 'off';
            app.GetInitialTrace2Button.Text = 'Get Initial Trace2';
            app.SetInitialTrace1Button = uibutton(app.MeasurementPanel, 'push');
            app.SetInitialTrace1Button.ButtonPushedFcn = createCallbackFcn(app, @SetInitialTrace1ButtonPushed, true);
            app.SetInitialTrace1Button.Enable = 'off';
            app.SetInitialTrace1Button.Text = 'Set Initial Trace1';
            app.SetInitialTrace2Button = uibutton(app.MeasurementPanel, 'push');
            app.SetInitialTrace2Button.ButtonPushedFcn = createCallbackFcn(app, @SetInitialTrace2ButtonPushed, true);
            app.SetInitialTrace2Button.Enable = 'off';
            app.SetInitialTrace2Button.Text = 'Set Initial Trace2';
            app.PauseButton = uibutton(app.MeasurementPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause';
            app.DataLogPanel = uipanel(app.LeftPanel);
            app.DataLogPanel.Title = 'Data Log';
            app.SaveButton = uibutton(app.DataLogPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.DataLogButton = uibutton(app.DataLogPanel, 'push');
            app.DataLogButton.ButtonPushedFcn = createCallbackFcn(app, @DataLogButtonPushed, true);
            app.DataLogButton.Enable = 'off';
            app.DataLogButton.Text = 'Start Data Log';
            app.ResetButton = uibutton(app.DataLogPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.LogIntervalEditFieldLabel = uilabel(app.DataLogPanel);
            app.LogIntervalEditFieldLabel.Text = 'Log Interval';
            app.LogIntervalEditField = uieditfield(app.DataLogPanel, 'numeric');
            app.LogIntervalEditField.Value = 0.05;
            app.sLabel = uilabel(app.DataLogPanel);
            app.sLabel.Text = 's';
            app.LeftCursorEditFieldLabel = uilabel(app.DataLogPanel);
            app.LeftCursorEditFieldLabel.Enable = 'off';
            app.LeftCursorEditFieldLabel.Text = 'Left Cursor';
            app.LeftCursorEditField = uieditfield(app.DataLogPanel, 'numeric');
            app.LeftCursorEditField.Enable = 'off';
            app.RightCursorEditFieldLabel = uilabel(app.DataLogPanel);
            app.RightCursorEditFieldLabel.Enable = 'off';
            app.RightCursorEditFieldLabel.Text = 'Right Cursor';
            app.RightCursorEditField = uieditfield(app.DataLogPanel, 'numeric');
            app.RightCursorEditField.Enable = 'off';
            app.MHzLabel = uilabel(app.DataLogPanel);
            app.MHzLabel.Text = 'MHz';
            app.MHzLabel_2 = uilabel(app.DataLogPanel);
            app.MHzLabel_2.Text = 'MHz';
            app.SetButton_2 = uibutton(app.DataLogPanel, 'push');
            app.SetButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetButton_2Pushed, true);
            app.SetButton_2.Enable = 'off';
            app.SetButton_2.Text = 'Set';
            app.CursorCheckBox = uicheckbox(app.DataLogPanel);
            app.CursorCheckBox.ValueChangedFcn = createCallbackFcn(app, @CursorCheckBoxValueChanged, true);
            app.CursorCheckBox.Text = 'Cursor';
            app.LoadDataButton = uibutton(app.DataLogPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ClearButton = uibutton(app.DataLogPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Enable = 'off';
            app.ClearButton.Text = 'Clear';
            app.RightPanel = uipanel(app.GridLayout);
            app.GaussianFitPanel = uipanel(app.RightPanel);
            app.GaussianFitPanel.Title = 'Gaussian Fit';
            app.gaussFitTrace = uiaxes(app.GaussianFitPanel);
            title(app.gaussFitTrace, 'Gaussian Peak Fitting')
            xlabel(app.gaussFitTrace, 'Frequency (MHz)')
            ylabel(app.gaussFitTrace, 'S11 (dB)')
            zlabel(app.gaussFitTrace, 'Z')
            app.GaussianFitButton = uibutton(app.GaussianFitPanel, 'push');
            app.GaussianFitButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianFitButtonPushed, true);
            app.GaussianFitButton.Text = 'Gaussian Fit';
            app.NumofGaussianPeaksSpinnerLabel = uilabel(app.GaussianFitPanel);
            app.NumofGaussianPeaksSpinnerLabel.Text = 'Num of Gaussian Peaks';
            app.NumofGaussianPeaksSpinner = uispinner(app.GaussianFitPanel);
            app.NumofGaussianPeaksSpinner.Limits = [1 10];
            app.NumofGaussianPeaksSpinner.Value = 4;
            app.FitParametersTextAreaLabel = uilabel(app.GaussianFitPanel);
            app.FitParametersTextAreaLabel.Text = 'Fit Parameters';
            app.FitParametersTextArea = uitextarea(app.GaussianFitPanel);
            app.MeasurementsPanel = uipanel(app.RightPanel);
            app.MeasurementsPanel.Title = 'Measurements';
            app.temporalTrace = uiaxes(app.MeasurementsPanel);
            title(app.temporalTrace, 'Temporal Trace')
            xlabel(app.temporalTrace, 'Frequency (MHz)')
            ylabel(app.temporalTrace, 'S11 (dB)')
            zlabel(app.temporalTrace, 'Z')
            app.temporalTrace.XLimitMethod = 'padded';
            app.temporalTrace.YLimitMethod = 'padded';
            app.diffTrace = uiaxes(app.MeasurementsPanel);
            title(app.diffTrace, 'Differential Trace')
            xlabel(app.diffTrace, 'Frequency (MHz)')
            ylabel(app.diffTrace, 'S11 (dB)')
            zlabel(app.diffTrace, 'Z')
            app.diffTrace.XLimitMethod = 'padded';
            app.diffTrace.YLimitMethod = 'padded';
            app.TimeSliderLabel = uilabel(app.MeasurementsPanel);
            app.TimeSliderLabel.Enable = 'off';
            app.TimeSliderLabel.Visible = 'off';
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.MeasurementsPanel);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.TimeSlider.Enable = 'off';
            app.TimeSlider.Visible = 'off';
            app.TimeLabel = uilabel(app.MeasurementsPanel);
            app.TimeLabel.Visible = 'off';
            app.TimeLabel.Text = 'TimeLabel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LCOSS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end